classdef TABLA_DE_PROPIEDADES_DE_LOS_FLUIDOS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CELDA_1_1                matlab.ui.control.NumericEditField
        CELDA_1_2                matlab.ui.control.NumericEditField
        CELDA_1_3                matlab.ui.control.NumericEditField
        CELDA_1_4                matlab.ui.control.NumericEditField
        CELDA_1_5                matlab.ui.control.NumericEditField
        CELDA_1_6                matlab.ui.control.NumericEditField
        CELDA_2_2                matlab.ui.control.NumericEditField
        CELDA_2_3                matlab.ui.control.NumericEditField
        CELDA_2_4                matlab.ui.control.NumericEditField
        CELDA_2_5                matlab.ui.control.NumericEditField
        CELDA_2_6                matlab.ui.control.NumericEditField
        CELDA_3_2                matlab.ui.control.NumericEditField
        CELDA_3_3                matlab.ui.control.NumericEditField
        CELDA_3_4                matlab.ui.control.NumericEditField
        CELDA_3_5                matlab.ui.control.NumericEditField
        CELDA_3_6                matlab.ui.control.NumericEditField
        CELDA_4_2                matlab.ui.control.NumericEditField
        CELDA_4_3                matlab.ui.control.NumericEditField
        CELDA_4_4                matlab.ui.control.NumericEditField
        CELDA_4_5                matlab.ui.control.NumericEditField
        CELDA_4_6                matlab.ui.control.NumericEditField
        CELDA_2_1                matlab.ui.control.NumericEditField
        CELDA_3_1                matlab.ui.control.NumericEditField
        CELDA_4_1                matlab.ui.control.NumericEditField
        MKSLabel                 matlab.ui.control.Label
        CGSLabel_2               matlab.ui.control.Label
        CGSLabel                 matlab.ui.control.Label
        INGLESLabel              matlab.ui.control.Label
        MKSLabel_5               matlab.ui.control.Label
        INGLESLabel_2            matlab.ui.control.Label
        SISTEMAABSOLUTOLabel     matlab.ui.control.Label
        SISTEMATERICOLabel       matlab.ui.control.Label
        CALCULARButton           matlab.ui.control.Button
        GRAVEDADESPECIFICALabel  matlab.ui.control.Label
        DENSIDADLabel            matlab.ui.control.Label
        PESOESPECIFICOLabel      matlab.ui.control.Label
        VOLUMENESPECIFICOLabel   matlab.ui.control.Label
        CLEARButton              matlab.ui.control.Button
        CREADORDETABLADEPROPIEDADESDEALGNFLUIDOLabel  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        FluidoListBoxLabel       matlab.ui.control.Label
        FluidoListBox            matlab.ui.control.ListBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULARButton
        function CALCULARButtonPushed(app, event)
            %COMPROBAR SI SE INGRESA ALGUN FLUIDO PREESTABLECIDO
            Fluido = app.FluidoListBox.Value;
            switch Fluido
                case 'Con propiedad conocida'
                case 'Agua'
                    app.CELDA_1_1.Value= 1;
                case 'Agua de mar'
                    app.CELDA_1_1.Value= 1.03;
                case 'Acetona'
                    app.CELDA_1_1.Value= 0.787;
                case 'Alcohol propilico'
                    app.CELDA_1_1.Value= 0.802;
                case 'Aceite de motor'
                    app.CELDA_1_1.Value= 0.91;
                case 'Aceite para transmision'
                    app.CELDA_1_1.Value= 0.878;
                case 'Glicerina'
                    app.CELDA_1_1.Value= 1.258;
                case 'Benceno'
                    app.CELDA_1_1.Value= 0.876;
                case 'Dioxido de Carbono'
                    app.CELDA_1_1.Value= 0.001964;
            end
            %INICIO DEL CASO EN EL QUE SI ES QUE NO SE INGRESA ALGUN VALOR
            %DE GRAVEDAD ESPECIFICA
            %--------------------------------------------------------------
            %SI SE INGRESA ALGUN VALOR DE DENSIDAD:
            %==============================================================
            if app.CELDA_2_1.Value ~= 0
                app.CELDA_1_1.Value = app.CELDA_2_1.Value/1000;
            end
            
            if app.CELDA_2_2.Value ~= 0
                app.CELDA_1_1.Value = app.CELDA_2_2.Value/1;
            end
            
            if app.CELDA_2_3.Value ~= 0
                app.CELDA_1_1.Value = app.CELDA_2_3.Value/62.4;
            end
            
            if app.CELDA_2_4.Value ~= 0
                app.CELDA_1_1.Value = app.CELDA_2_4.Value*9.81/1000;
            end
             
            if app.CELDA_2_5.Value ~= 0
                app.CELDA_1_1.Value = app.CELDA_2_5.Value*981/1;
            end
            
            if app.CELDA_2_6.Value ~= 0
                app.CELDA_1_1.Value = app.CELDA_2_6.Value*32.2/62.4;
            end
            %==============================================================
            
            %SI SE INGRESA ALGUN VALOR DE PESO ESPECIFICO:
            %==============================================================
            if app.CELDA_3_1.Value ~= 0
                app.CELDA_1_1.Value = app.CELDA_3_1.Value/9810;
            end
            
            if app.CELDA_3_2.Value ~= 0
                app.CELDA_1_1.Value = app.CELDA_3_2.Value/981;
            end
            
            if app.CELDA_3_3.Value ~= 0
                app.CELDA_1_1.Value = app.CELDA_3_3.Value/2009.28;
            end
            
            if app.CELDA_3_4.Value ~= 0
                app.CELDA_1_1.Value = app.CELDA_3_4.Value/1000;
            end
            
            if app.CELDA_3_5.Value ~= 0
                app.CELDA_1_1.Value = app.CELDA_3_5.Value/1;
            end
            
            if app.CELDA_3_6.Value ~= 0
                app.CELDA_1_1.Value = app.CELDA_3_6.Value/62.4;
            end
            %==============================================================
            
            %SI INGRESESAS VOLUMEN ESPECIFICO:
            %==============================================================
            if app.CELDA_4_1.Value ~= 0
                app.CELDA_1_1.Value = 1/app.CELDA_4_1.Value/1000;
            end
            
            if app.CELDA_4_2.Value ~= 0
                app.CELDA_1_1.Value = 1/app.CELDA_4_2.Value/1;
            end
            
            if app.CELDA_4_3.Value ~= 0
                app.CELDA_1_1.Value = 1/app.CELDA_4_3.Value/62.4;
            end
            
            if app.CELDA_4_4.Value ~= 0
                app.CELDA_1_1.Value = 1/app.CELDA_4_4.Value/1000;
            end
            
            if app.CELDA_4_5.Value ~= 0
                app.CELDA_1_1.Value = 1/app.CELDA_4_5.Value/1;
            end
            
            if app.CELDA_4_6.Value ~= 0
                app.CELDA_1_1.Value = 1/app.CELDA_4_6.Value/62.4;
            end
            %==============================================================
            
            %--------------------------------------------------------------
            
            
            %--------------------------------------------------------------
            %INICIO DEL CASO EN EL QUE SE HAYA INTRODUCIDO LA GRAVEDAD
            %ESPECIFICA O QUE YA SE HAYA OBTENIDO
            
            %LEER TODOS LOS VALORES DE LAS GRAVEDADES ESPECIFICAS
            s1 = app.CELDA_1_1.Value;
            s2 = app.CELDA_1_2.Value;
            s3 = app.CELDA_1_3.Value;
            s4 = app.CELDA_1_4.Value;
            s5 = app.CELDA_1_5.Value;
            s6 = app.CELDA_1_6.Value;
            
            %INICIO DE LA COMPROBACION DE SI SE INGRESO ALGUN VALOR EN LA
            %GRAVEDAD ESPECIFICA O SI SE OBTUVO EL VALOR
            if s1 ~= 0
                app.CELDA_1_2.Value = app.CELDA_1_1.Value;
                app.CELDA_1_3.Value = app.CELDA_1_1.Value;
                app.CELDA_1_4.Value = app.CELDA_1_1.Value;
                app.CELDA_1_5.Value = app.CELDA_1_1.Value;
                app.CELDA_1_6.Value = app.CELDA_1_1.Value;
            end
            if s2 ~= 0
                app.CELDA_1_1.Value = app.CELDA_1_2.Value;
                app.CELDA_1_3.Value = app.CELDA_1_2.Value;
                app.CELDA_1_4.Value = app.CELDA_1_2.Value;
                app.CELDA_1_5.Value = app.CELDA_1_2.Value;
                app.CELDA_1_6.Value = app.CELDA_1_2.Value;
            end
            if s3 ~= 0
                app.CELDA_1_1.Value = app.CELDA_1_3.Value;
                app.CELDA_1_2.Value = app.CELDA_1_3.Value;
                app.CELDA_1_4.Value = app.CELDA_1_3.Value;
                app.CELDA_1_5.Value = app.CELDA_1_3.Value;
                app.CELDA_1_6.Value = app.CELDA_1_3.Value;
            end
            if s4 ~= 0
                app.CELDA_1_1.Value = app.CELDA_1_4.Value;
                app.CELDA_1_2.Value = app.CELDA_1_4.Value;
                app.CELDA_1_3.Value = app.CELDA_1_4.Value;
                app.CELDA_1_5.Value = app.CELDA_1_4.Value;
                app.CELDA_1_6.Value = app.CELDA_1_4.Value;
            end
            if s5 ~= 0
                app.CELDA_1_1.Value = app.CELDA_1_5.Value;
                app.CELDA_1_2.Value = app.CELDA_1_5.Value;
                app.CELDA_1_3.Value = app.CELDA_1_5.Value;
                app.CELDA_1_4.Value = app.CELDA_1_5.Value;
                app.CELDA_1_6.Value = app.CELDA_1_5.Value;
            end
            if s6 ~= 0
                app.CELDA_1_1.Value = app.CELDA_1_6.Value;
                app.CELDA_1_2.Value = app.CELDA_1_6.Value;
                app.CELDA_1_3.Value = app.CELDA_1_6.Value;
                app.CELDA_1_4.Value = app.CELDA_1_6.Value;
                app.CELDA_1_5.Value = app.CELDA_1_6.Value;
            end
            
            %SI ES QUE SE INGRESO ALGUN VALOR SE PROCEDE A HACER TODAS LAS
            %OPERACIONES CORRESPONDIENTES
            s=app.CELDA_1_6.Value;
            if s ~= 0
                app.CELDA_2_1.Value = s*1000;
                app.CELDA_2_2.Value = s*1;
                app.CELDA_2_3.Value = s*62.4;
                app.CELDA_2_4.Value = s*1000/9.81;
                app.CELDA_2_5.Value = s*1/981;
                app.CELDA_2_6.Value = s*62.4/32.2;
                
                app.CELDA_3_1.Value = app.CELDA_2_1.Value*9.81;
                app.CELDA_3_2.Value = app.CELDA_2_2.Value*981;
                app.CELDA_3_3.Value = app.CELDA_2_3.Value*32.2;
                app.CELDA_3_4.Value = app.CELDA_2_1.Value;
                app.CELDA_3_5.Value = app.CELDA_2_2.Value;
                app.CELDA_3_6.Value = app.CELDA_2_3.Value;
                
                app.CELDA_4_1.Value = 1/app.CELDA_2_1.Value;
                app.CELDA_4_2.Value = 1/app.CELDA_2_2.Value;
                app.CELDA_4_3.Value = 1/app.CELDA_2_3.Value;
                app.CELDA_4_4.Value = app.CELDA_4_1.Value;
                app.CELDA_4_5.Value = app.CELDA_4_2.Value;
                app.CELDA_4_6.Value = app.CELDA_4_3.Value;
            end
            %FIN DEL CASO EN EL QUE SE HAYA INTRODUCIODO ALGUN VALOR EN LA
            %GRAVEDAD ESPECIFICA
            %--------------------------------------------------------------
            
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            %LIMPIA TODAS LAS CASILLAS
            app.CELDA_1_1.Value = 0;
            app.CELDA_1_2.Value = 0;
            app.CELDA_1_3.Value = 0;
            app.CELDA_1_4.Value = 0;
            app.CELDA_1_5.Value = 0;
            app.CELDA_1_6.Value = 0;
            app.CELDA_2_1.Value = 0;
            app.CELDA_2_2.Value = 0;
            app.CELDA_2_3.Value = 0;
            app.CELDA_2_4.Value = 0;
            app.CELDA_2_5.Value = 0;
            app.CELDA_2_6.Value = 0;
            app.CELDA_3_1.Value = 0;
            app.CELDA_3_2.Value = 0;
            app.CELDA_3_3.Value = 0;
            app.CELDA_3_4.Value = 0;
            app.CELDA_3_5.Value = 0;
            app.CELDA_3_6.Value = 0;
            app.CELDA_4_1.Value = 0;
            app.CELDA_4_2.Value = 0;
            app.CELDA_4_3.Value = 0;
            app.CELDA_4_4.Value = 0;
            app.CELDA_4_5.Value = 0;
            app.CELDA_4_6.Value = 0;
        end

        % Value changed function: FluidoListBox
        function FluidoListBoxValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CELDA_1_1 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_1_2 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_1_3 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_1_4 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_1_5 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_1_6 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_2_2 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_2_3 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_2_4 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_2_5 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_2_6 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_3_2 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_3_3 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_3_4 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_3_5 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_3_6 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_4_2 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_4_3 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_4_4 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_4_5 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_4_6 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_2_1 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_3_1 = uieditfield(app.UIFigure, 'numeric');
            app.CELDA_4_1 = uieditfield(app.UIFigure, 'numeric');
            app.MKSLabel = uilabel(app.UIFigure);
            app.MKSLabel.Text = 'MKS';
            app.CGSLabel_2 = uilabel(app.UIFigure);
            app.CGSLabel_2.Text = 'CGS';
            app.CGSLabel = uilabel(app.UIFigure);
            app.CGSLabel.Text = 'CGS';
            app.INGLESLabel = uilabel(app.UIFigure);
            app.INGLESLabel.Text = 'INGLES';
            app.MKSLabel_5 = uilabel(app.UIFigure);
            app.MKSLabel_5.Text = 'MKS';
            app.INGLESLabel_2 = uilabel(app.UIFigure);
            app.INGLESLabel_2.Text = 'INGLES';
            app.SISTEMAABSOLUTOLabel = uilabel(app.UIFigure);
            app.SISTEMAABSOLUTOLabel.Text = 'SISTEMA ABSOLUTO';
            app.SISTEMATERICOLabel = uilabel(app.UIFigure);
            app.SISTEMATERICOLabel.Text = 'SISTEMA TEÓRICO';
            app.CALCULARButton = uibutton(app.UIFigure, 'push');
            app.CALCULARButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULARButtonPushed, true);
            app.CALCULARButton.Text = 'CALCULAR';
            app.GRAVEDADESPECIFICALabel = uilabel(app.UIFigure);
            app.GRAVEDADESPECIFICALabel.Text = 'GRAVEDAD ESPECIFICA';
            app.DENSIDADLabel = uilabel(app.UIFigure);
            app.DENSIDADLabel.Text = 'DENSIDAD';
            app.PESOESPECIFICOLabel = uilabel(app.UIFigure);
            app.PESOESPECIFICOLabel.Text = 'PESO ESPECIFICO';
            app.VOLUMENESPECIFICOLabel = uilabel(app.UIFigure);
            app.VOLUMENESPECIFICOLabel.Text = 'VOLUMEN ESPECIFICO';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.CREADORDETABLADEPROPIEDADESDEALGNFLUIDOLabel = uilabel(app.UIFigure);
            app.CREADORDETABLADEPROPIEDADESDEALGNFLUIDOLabel.Text = 'CREADOR DE TABLA DE PROPIEDADES DE ALGÚN FLUIDO';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '(Ingresar cualquer valor conocido del fluido; es importante que se igrese UN SOLO VALOR, o seleccionar un fluido de la tabla desplegable)';
            app.FluidoListBoxLabel = uilabel(app.UIFigure);
            app.FluidoListBoxLabel.Text = 'Fluido';
            app.FluidoListBox = uilistbox(app.UIFigure);
            app.FluidoListBox.Items = {'Con propiedad conocida', 'Agua', 'Agua de mar', 'Acetona', 'Alcohol propilico', 'Aceite de motor', 'Aceite para transmision', 'Glicerina', 'Benceno', 'Dioxido de Carbono'};
            app.FluidoListBox.ValueChangedFcn = createCallbackFcn(app, @FluidoListBoxValueChanged, true);
            app.FluidoListBox.Value = 'Con propiedad conocida';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TABLA_DE_PROPIEDADES_DE_LOS_FLUIDOS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end